classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUIFigure         matlab.ui.Figure
        Image4              matlab.ui.control.Image
        IniciarSesinButton  matlab.ui.control.Button
        RegistrameButton    matlab.ui.control.Button
        Image2              matlab.ui.control.Image
    end

    
    properties (Access = private)
        Users= load("Usuarios.mat") % Description
        %Users_m=load(Usuarios.mat) % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegistrameButton
        function RegistrameButtonPushed(app, event)
            set(app.RegistrameButton,'Enable','Off')
            waitfor(app2(app.Users))
            set(app.RegistrameButton,'Enable','On')
        end

        % Button pushed function: IniciarSesinButton
        function IniciarSesinButtonPushed(app, event)
            set(app.IniciarSesinButton,'Enable','Off')
            app3
            delete(app)
            %set(app.IniciarSesinButton,'Enable','On')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECGUIFigure = uifigure('Visible', 'off');
            app.ECGUIFigure.Name = 'ECG';
            app.Image2 = uiimage(app.ECGUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo-upiita.png');
            app.RegistrameButton = uibutton(app.ECGUIFigure, 'push');
            app.RegistrameButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrameButtonPushed, true);
            app.RegistrameButton.Text = 'Registrame';
            app.IniciarSesinButton = uibutton(app.ECGUIFigure, 'push');
            app.IniciarSesinButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarSesinButtonPushed, true);
            app.IniciarSesinButton.Text = 'Iniciar Sesión';
            app.Image4 = uiimage(app.ECGUIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'corazón.jpg');
            % Show the figure after all components are created
            app.ECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUIFigure)
        end
    end
end