classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUIFigure                     matlab.ui.Figure
        UsuarioregistradoconexitoLabel  matlab.ui.control.Label
        UsuarioexistenteLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RegresaralmenButton             matlab.ui.control.Button
        RegistrarmeButton               matlab.ui.control.Button
        ContraseaEditField              matlab.ui.control.EditField
        ContraseaEditFieldLabel         matlab.ui.control.Label
        CorreoEditField                 matlab.ui.control.EditField
        CorreoEditFieldLabel            matlab.ui.control.Label
        ApellidoEditField               matlab.ui.control.EditField
        ApellidoEditFieldLabel          matlab.ui.control.Label
        NombreEditField                 matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        RegistrodeusuarioLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        Users=NaN % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Users)
            %app.Users=Users.sers;
            app.Users=load("Usuarios.mat").sers;
        end

        % Button pushed function: RegresaralmenButton
        function RegresaralmenButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ECGUIFigure
        function ECGUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: RegistrarmeButton
        function RegistrarmeButtonPushed(app, event)
            disp(size(app.NombreEditField.Value))
            if(~(size(app.NombreEditField.Value,2)<5 || size(app.ApellidoEditField.Value,2)<5 || size(app.CorreoEditField.Value,2)<5 || size(app.ContraseaEditField.Value,2)<5))

                User = struct("Nombre","","Apellido","","Mail","","Pass","");
                Userr = User();
                Userr.Nombre=app.NombreEditField.Value;
                Userr.Apellido=app.ApellidoEditField.Value;
                Userr.Mail=app.CorreoEditField.Value;
                Userr.Pass=app.ContraseaEditField.Value;
                exists=0;
                for usu=app.Users
                    disp(usu.Mail)
                    if strcmp(usu.Mail,Userr.Mail)
                        exists=1;
                    end
                end
                if not(exists)
                    app.Users=[app.Users,Userr];
                    sers=app.Users;
                    save Usuarios.mat sers;
                    app.UsuarioregistradoconexitoLabel.Visible="on";
                    pause(.8);
                    delete(app);
                else
                    app.UsuarioexistenteLabel.Visible="on";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECGUIFigure = uifigure('Visible', 'off');
            app.ECGUIFigure.Name = 'ECG';
            app.ECGUIFigure.CloseRequestFcn = createCallbackFcn(app, @ECGUIFigureCloseRequest, true);
            app.RegistrodeusuarioLabel = uilabel(app.ECGUIFigure);
            app.RegistrodeusuarioLabel.Text = 'Registro de usuario';
            app.NombreEditFieldLabel = uilabel(app.ECGUIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.ECGUIFigure, 'text');
            app.NombreEditField.CharacterLimits = [3 20];
            app.NombreEditField.InputType = 'letters';
            app.ApellidoEditFieldLabel = uilabel(app.ECGUIFigure);
            app.ApellidoEditFieldLabel.Text = 'Apellido';
            app.ApellidoEditField = uieditfield(app.ECGUIFigure, 'text');
            app.ApellidoEditField.CharacterLimits = [3 20];
            app.ApellidoEditField.InputType = 'letters';
            app.CorreoEditFieldLabel = uilabel(app.ECGUIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.ECGUIFigure, 'text');
            app.CorreoEditField.CharacterLimits = [15 100];
            app.ContraseaEditFieldLabel = uilabel(app.ECGUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.ECGUIFigure, 'text');
            app.ContraseaEditField.CharacterLimits = [8 10];
            app.ContraseaEditField.InputType = 'alphanumerics';
            app.ContraseaEditField.Tooltip = {''};
            app.RegistrarmeButton = uibutton(app.ECGUIFigure, 'push');
            app.RegistrarmeButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarmeButtonPushed, true);
            app.RegistrarmeButton.Text = 'Registrarme';
            app.RegresaralmenButton = uibutton(app.ECGUIFigure, 'push');
            app.RegresaralmenButton.ButtonPushedFcn = createCallbackFcn(app, @RegresaralmenButtonPushed, true);
            app.RegresaralmenButton.Text = 'Regresar al menú';
            app.Image = uiimage(app.ECGUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'corazón.jpg');
            app.UsuarioexistenteLabel = uilabel(app.ECGUIFigure);
            app.UsuarioexistenteLabel.Visible = 'off';
            app.UsuarioexistenteLabel.Text = 'Usuario existente';
            app.UsuarioregistradoconexitoLabel = uilabel(app.ECGUIFigure);
            app.UsuarioregistradoconexitoLabel.Visible = 'off';
            app.UsuarioregistradoconexitoLabel.Text = '¡Usuario registrado con exito!';
            % Show the figure after all components are created
            app.ECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUIFigure)
        end
    end
end