classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUIFigure              matlab.ui.Figure
        UsuarioocontraseaincorrectoLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        LoguearseButton          matlab.ui.control.Button
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        CorreoEditField          matlab.ui.control.EditField
        CorreoEditFieldLabel     matlab.ui.control.Label
        IniciarsesinLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Users=NaN % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Users)
            app.Users=load("Usuarios.mat").sers;
        end

        % Button pushed function: LoguearseButton
        function LoguearseButtonPushed(app, event)
            curr_mail=app.CorreoEditField.Value;
            curr_pass=app.ContraseaEditField.Value;
            exists=0;
            for usu=app.Users
                if strcmp(usu.Mail,curr_mail) && strcmp(usu.Pass,curr_pass)
                    exists=1;
                end
            end
            if not(exists)
                app.UsuarioocontraseaincorrectoLabel.Visible="on";
            else
                app4();
                delete(app)
            end
        end

        % Close request function: ECGUIFigure
        function ECGUIFigureCloseRequest(app, event)
            app1();
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECGUIFigure = uifigure('Visible', 'off');
            app.ECGUIFigure.Name = 'ECG';
            app.ECGUIFigure.CloseRequestFcn = createCallbackFcn(app, @ECGUIFigureCloseRequest, true);
            app.IniciarsesinLabel = uilabel(app.ECGUIFigure);
            app.IniciarsesinLabel.Text = 'Iniciar sesión';
            app.CorreoEditFieldLabel = uilabel(app.ECGUIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.ECGUIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.ECGUIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.ECGUIFigure, 'text');
            app.LoguearseButton = uibutton(app.ECGUIFigure, 'push');
            app.LoguearseButton.ButtonPushedFcn = createCallbackFcn(app, @LoguearseButtonPushed, true);
            app.LoguearseButton.Text = 'Loguearse';
            app.Image = uiimage(app.ECGUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'corazón.jpg');
            app.UsuarioocontraseaincorrectoLabel = uilabel(app.ECGUIFigure);
            app.UsuarioocontraseaincorrectoLabel.Visible = 'off';
            app.UsuarioocontraseaincorrectoLabel.Text = 'Usuario o contraseña incorrecto';
            % Show the figure after all components are created
            app.ECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUIFigure)
        end
    end
end