classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUIFigure                    matlab.ui.Figure
        EscanearButton                 matlab.ui.control.Button
        EdadEditFieldLabel_2           matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        EdadEditField                  matlab.ui.control.EditField
        EdadEditFieldLabel             matlab.ui.control.Label
        NumerodeExprdienteEditField    matlab.ui.control.EditField
        NumerodeExprdienteEditFieldLabel  matlab.ui.control.Label
        AnalizarButton                 matlab.ui.control.Button
        DatosclinicosdelpacienteLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        FileName=NaN % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Users)
     
        end

        % Callback function
        function RegresaralmenButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ECGUIFigure
        function ECGUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: EscanearButton
        function EscanearButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.jpg;*.png;'}, 'Select File to Open');
            fullname = [filepath, filename];
            app.FileName=fullname;
            ImageFile = imread(fullname);
            imagesc(app.UIAxes, ImageFile);
            set(app.AnalizarButton,'Enable','ON');
        end

        % Button pushed function: AnalizarButton
        function AnalizarButtonPushed(app, event)
            if((size(app.NumerodeExprdienteEditField.Value,2)==6 && str2num(app.EdadEditField.Value)>0 && str2num(app.EdadEditField.Value)<99))
                app5(app.FileName,app.NumerodeExprdienteEditField.Value,app.EdadEditField.Value,app.Switch.Value);
                delete(app)
            else
                uialert(app.ECGUIFigure ,['Por favor, escribe correctamente los campos',char(10),'Numero de expediente (6 digitos) y',char(10),'0>Edad<99'],'Campos incompletos');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECGUIFigure = uifigure('Visible', 'off');
            app.ECGUIFigure.Name = 'ECG';
            app.ECGUIFigure.CloseRequestFcn = createCallbackFcn(app, @ECGUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ECGUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.DatosclinicosdelpacienteLabel = uilabel(app.ECGUIFigure);
            app.DatosclinicosdelpacienteLabel.Text = 'Datos clinicos del paciente';
            app.AnalizarButton = uibutton(app.ECGUIFigure, 'push');
            app.AnalizarButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarButtonPushed, true);
            app.AnalizarButton.Enable = 'off';
            app.AnalizarButton.Text = 'Analizar';
            app.NumerodeExprdienteEditFieldLabel = uilabel(app.ECGUIFigure);
            app.NumerodeExprdienteEditFieldLabel.Text = 'Numero de Exprdiente';
            app.NumerodeExprdienteEditField = uieditfield(app.ECGUIFigure, 'text');
            app.NumerodeExprdienteEditField.InputType = 'digits';
            app.EdadEditFieldLabel = uilabel(app.ECGUIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.ECGUIFigure, 'text');
            app.EdadEditField.InputType = 'digits';
            app.Switch = uiswitch(app.ECGUIFigure, 'slider');
            app.Switch.Items = {'Hombre', 'Mujer'};
            app.Switch.Value = 'Hombre';
            app.EdadEditFieldLabel_2 = uilabel(app.ECGUIFigure);
            app.EdadEditFieldLabel_2.Text = 'Sexo';
            app.EscanearButton = uibutton(app.ECGUIFigure, 'push');
            app.EscanearButton.ButtonPushedFcn = createCallbackFcn(app, @EscanearButtonPushed, true);
            app.EscanearButton.Text = 'Escanear';
            % Show the figure after all components are created
            app.ECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUIFigure)
        end
    end
end