classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUIFigure                   matlab.ui.Figure
        Cargando                      matlab.ui.control.Image
        SeleccionartiraderitmoButton  matlab.ui.control.Button
        ECGMejoradoLabel              matlab.ui.control.Label
        ECGInvertidoLabel             matlab.ui.control.Label
        ECGBinarizadoLabel            matlab.ui.control.Label
        ECGOriginalLabel              matlab.ui.control.Label
        ECGMejorado                   matlab.ui.control.UIAxes
        ECGInvertido                  matlab.ui.control.UIAxes
        ECGBinarizado                 matlab.ui.control.UIAxes
        ECGOriginal                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        FileName=NaN % Description
        BW=NaN
        Expediente=NaN
        Edad=NaN
        Sexo=NaN
    end

    methods (Access = private)

        function results = Procesa(app)
            
            retardo_plot=1

            color=imread(app.FileName);
            color=imresize(color,[1600,NaN]);
            umbral=120;
            %Estimacion del mejor tono para calcular las distancias
            estima=tanh(mean((mean(mean(color)))-240)/10)*15 +45;
            %Se calculan las distancias (Distancia euclidiana) entre
            %nuestro vector de referencia y nuestro tono a filtrar o
            %conservar
            distancias=sqrt(sum(double(color-estima).^2,3));
            nuea=(distancias>=umbral)*255;
            nsd=zeros(size(nuea));
            nsd(:,:,1)=nuea;
            nsd(:,:,2)=nuea;
            nsd(:,:,3)=nuea;
            nueva=nsd;
            %Definiendo las estructuras para las operaciones morfologicas
            se1 = strel('line',1,2);
            se2 = offsetstrel('ball',2,6);
            se3 = strel('disk',1);
            se4 = strel('line',4,10);
            se5 = strel('diamond',3);
            se6 = strel('octagon',3);
            se7 = strel('disk',3);
            
            Nueva=nueva;
            IM=imcomplement(Nueva);
            pause(retardo_plot)
            imagesc(app.ECGInvertido, imcomplement(color));
            pause(retardo_plot)
            imagesc(app.ECGBinarizado, imbinarize(IM));

            IM2 = imdilate(IM,se1);

            IM3 = imdilate(IM2,se3);

            IM4 = imclose(IM3,se1);

            IM5=imcomplement(IM4);

            Eg=IM5;%imread(strcat(nom, '_OM2.jpg'));
            I = rgb2gray(Eg);
            BW = I;%imbinarize(I);


            pause(retardo_plot)
            app.BW=BW
            imagesc(app.ECGMejorado, medfilt2(BW,[6,6]));

            app.SeleccionartiraderitmoButton.Visible="on"
            app.SeleccionartiraderitmoButton.Enable="on"
            app.Cargando.Visible="off"

            results=0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ECGOriginal, Expediente, Edad, Sexo)
            app.Expediente=Expediente;
            app.Edad=Edad;
            app.Sexo=Sexo;
            app.FileName=ECGOriginal;
            ImageFile = imread(app.FileName);
            imagesc(app.ECGOriginal, ImageFile);
            app.Procesa();
        end

        % Button pushed function: SeleccionartiraderitmoButton
        function SeleccionartiraderitmoButtonPushed(app, event)
            close all
            Tira=imcrop(app.BW);
            close all
            nfft=50
            Fs=3e3/7;
            f = Fs*(-(nfft/2)+1:(nfft/2))/nfft;
    if  sum(sum(Tira==0))/numel(Tira)>.11
        Tira=medfilt2(medfilt2(Tira,[6,6]),[3,3])>.9;
    else
        Tira=medfilt2(Tira,[6,6])>.9;
    end
        Tira=Tira(2:end-2,2:end-2);
            %ancs=[ancs size(color,2)];
            k=1;
           

              S=size(Tira);
    Fs=2820/11;%FS aproximada
    Ts=1/Fs
    for b=1:S(2)
        pixels_0=find(Tira(:,b)==0);
        m_t=mean(pixels_0);
        y=median(pixels_0(abs(pixels_0-m_t)<.05*S(1)));
        x=b*Ts*1j;
        s_k(b)=x+y;
    end
    %quitar nulos al inicio
    for b=1:S(2)
        if ~isnan(s_k(b))
            s_k(1)=s_k(b);
            break
        end
    end
    %quitar nulos al final
    for b=S(2):-1:1
        if ~isnan(s_k(b))
            s_k(end)=s_k(b);
            break
        end
    end
    %Interpolando
    V=s_k;
    X = ~isnan(V);
    Y = cumsum(X-diff([1,X])/2);
    Z = interp1(1:nnz(X),V(X),Y);
    s_k=Z;
    ims=imag(s_k);
    imr=real(s_k);

    %Quitando la tendencia%
    [p,s,mu] = polyfit((1:numel(imr))',imr,6);
    f_y = polyval(p,(1:numel(imr))',[],mu);
    ECG_data = imr - f_y';        % Detrend data
    s_k=ECG_data+(1j*ims);
    Z=s_k;%ECG Vectorizado


app6(s_k,app.Expediente,app.Edad,app.Sexo)
delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECGUIFigure = uifigure('Visible', 'off');
            app.ECGUIFigure.Name = 'ECG';
            app.ECGOriginal = uiaxes(app.ECGUIFigure);
            title(app.ECGOriginal, 'Title')
            xlabel(app.ECGOriginal, 'X')
            ylabel(app.ECGOriginal, 'Y')
            zlabel(app.ECGOriginal, 'Z')
            app.ECGOriginal.Visible = 'off';
            app.ECGBinarizado = uiaxes(app.ECGUIFigure);
            title(app.ECGBinarizado, 'Title')
            xlabel(app.ECGBinarizado, 'X')
            ylabel(app.ECGBinarizado, 'Y')
            zlabel(app.ECGBinarizado, 'Z')
            app.ECGBinarizado.Visible = 'off';
            app.ECGInvertido = uiaxes(app.ECGUIFigure);
            title(app.ECGInvertido, 'Title')
            xlabel(app.ECGInvertido, 'X')
            ylabel(app.ECGInvertido, 'Y')
            zlabel(app.ECGInvertido, 'Z')
            app.ECGInvertido.Visible = 'off';
            app.ECGMejorado = uiaxes(app.ECGUIFigure);
            title(app.ECGMejorado, 'Title')
            xlabel(app.ECGMejorado, 'X')
            ylabel(app.ECGMejorado, 'Y')
            zlabel(app.ECGMejorado, 'Z')
            app.ECGMejorado.Visible = 'off';
            app.ECGOriginalLabel = uilabel(app.ECGUIFigure);
            app.ECGOriginalLabel.Text = 'ECG Original';
            app.ECGBinarizadoLabel = uilabel(app.ECGUIFigure);
            app.ECGBinarizadoLabel.Text = 'ECG Binarizado';
            app.ECGInvertidoLabel = uilabel(app.ECGUIFigure);
            app.ECGInvertidoLabel.Text = 'ECG Invertido';
            app.ECGMejoradoLabel = uilabel(app.ECGUIFigure);
            app.ECGMejoradoLabel.Text = 'ECG Mejorado';
            app.SeleccionartiraderitmoButton = uibutton(app.ECGUIFigure, 'push');
            app.SeleccionartiraderitmoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionartiraderitmoButtonPushed, true);
            app.SeleccionartiraderitmoButton.Visible = 'off';
            app.SeleccionartiraderitmoButton.Text = 'Seleccionar tira de ritmo';
            app.Cargando = uiimage(app.ECGUIFigure);
            app.Cargando.ImageSource = fullfile(pathToMLAPP, 'cargando.gif');
            % Show the figure after all components are created
            app.ECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUIFigure)
        end
    end
end