classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGUIFigure                   matlab.ui.Figure
        SexoLabel                     matlab.ui.control.Label
        EdadLabel                     matlab.ui.control.Label
        ExpedienteLabel               matlab.ui.control.Label
        RESULTADOLabel                matlab.ui.control.Label
        DeseaguardarelanaliticoLabel  matlab.ui.control.Label
        NoButton                      matlab.ui.control.Button
        SiButton                      matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        ComentariosLabel              matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        TiraderitmoLabel              matlab.ui.control.Label
        PW                            matlab.ui.control.UIAxes
        QRS                           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        s_k=NaN % Description
        Expediente=NaN
        Edad=NaN
        Sexo=NaN
    end

    methods (Access = private)
        % Save this fcn somewhere on the path
        function txt = myupdatefcn(trash,event)
            pos = get(event,'Position');
            dts = get(event.Target,'Tag');
            txt = {dts,...
                ['X: ',num2str(pos(1))],...
                ['Y: ',num2str(pos(2))]};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, s_k, Expediente, Edad, Sexo)
            app.s_k=s_k;
            app.Expediente=Expediente;
            app.Edad=Edad;
            app.Sexo=Sexo;
            % % app.s_k=load("s_k.mat").s_k;
            % %s_k=app.s_k;
            % % save s_k.mat s_k
            % [p,s,mu] = polyfit((1:numel(app.s_k))',app.s_k,6);
            % f_y = polyval(p,(1:numel(app.s_k))',[],mu);
            % ECG_data = app.s_k - f_y';        % Detrend data
            % app.s_k=ECG_data;
            dsf=3;
            s_k=downsample(s_k,dsf);
            s_k=movmean(real(s_k),3)+(1j*imag(s_k));
            s_k=real(s_k);
            s_k1=s_k;
            app.s_k=s_k;

            %Calcula pulso
            atmp=s_k;
            atmp(atmp<0)=0;
            % findpeaks(atmp.^6,'MinPeakDistance',25,'MinPeakHeight',.8*max(atmp));
            % ylabel("Altura")
            % xlabel("Muestras")
            % title("Función findpeaks sin parámetros")
            % legend("ECG","Picos encontrados")
            [a,l]=findpeaks(atmp.^6,'MinPeakDistance',25,'MinPeakHeight',.8*max(atmp));
            mind=mean(diff(l));
            %etapa de normalizacion
            %app.s_k=app.s_k-min(app.s_k);
            rango_d=max(app.s_k)-min(app.s_k);
            app.s_k=app.s_k./(.5*(rango_d));
            rango_d=max(app.s_k)-min(app.s_k);
            %app.s_k=app.s_k-mean(app.s_k);
            mind=.8*mind;
            %findpeaks(lowpass(sgolayfilt(app.s_k,7,21),.05),'MinPeakHeight',.1*max(app.s_k),'MinPeakDistance',mind/9,'Annotate','extents','WidthReference','halfheight')
            %findpeaks(sgolayfilt(app.s_k,7,21),'MinPeakProminence',.1*max(app.s_k),'MinPeakDistance',mind/9,'Annotate','extents','WidthReference','halfprom')
            %title('Signal Peak Widths')
            %hold on
            smoothECG = sgolayfilt(app.s_k,7,21);
            [a,l,w,p]=findpeaks(smoothECG,'MinPeakProminence',.1*max(app.s_k),'MinPeakDistance',mind/9,'Annotate','extents','WidthReference','halfprom');
            %title('Signal Peak Widths')

            am=(a-(p/2));


            bordes_media_promin=[]
            %Contruir vectores para graficar los anchos
            for i=1:size(a,2)
                auxaltura=a(i)-(p(i)/2);
                for j=l(i):-1:l(i)-200
                    if smoothECG(j)<auxaltura
                        dif=smoothECG(j+1)-smoothECG(j);
                        bordes_media_promin(i,1)=j+((auxaltura-smoothECG(j))/dif);
                        bordes_media_promin(i,2)=bordes_media_promin(i,1)+w(i);
                        break
                    end
                end
            end
            p1 = plot(app.QRS,smoothECG);
            hold(app.QRS,"on");
            p2 = scatter(app.QRS,l,a,'v');
            p3 = plot(app.QRS,[l',l']',[a',a'-p']',"r--");
            p4 = plot(app.QRS,bordes_media_promin',[a'-(p/2)',a'-(p/2)']',"m","tag",num2str(w));
            h = [p1;p2;p3(1);p4];
            legend(app.QRS,h,"Señal suavizada","Picos","Prominencia","Anchos");
            % datacursormode(app.QRS,"on")
            % dcm = datacursormode(app.ECGUIFigure);
            % set(dcm,'UpdateFcn',@app.myupdatefcn)


            [rh,locs_Rwave] = findpeaks(app.s_k,'MinPeakHeight',0.2,...
                'MinPeakDistance',mind);

            ECG_inverted = -app.s_k;
            [sh,locs_Swave]=findpeaks(ECG_inverted,'MinPeakHeight',0.2,...
                'MinPeakDistance',mind);
            % subplot(212)
            % findpeaks(ECG_inverted,'MinPeakHeight',0.2,...
            %     'MinPeakDistance',mind);
            % hold on
            %
            % ylabel("Altura")
            % xlabel("Muestras")
            % legend("-ECG","Picos/Valles S")
            % title("ECG invertido-puntos S")
            % subplot(211)
            % plot(app.s_k)
            % ylabel("Altura")
            % xlabel("Muestras")
            % title("ECG Original")
            % grid on
            % legend("ECG")
            % drawnow
            % pause

            [mh,min_locs] = findpeaks(-smoothECG,'MinPeakProminence',.1*rango_d);

            %Peaks between
            locs_Qwave = min_locs(smoothECG(min_locs)>.9*max(-sh) & smoothECG(min_locs)<.9*max(smoothECG));%.3*(max(app.s_k)-min(app.s_k)-1));
            % figure
            % plot(smoothECG);
            % hold on
            % plot(locs_Qwave,smoothECG(locs_Qwave),'rs','MarkerFaceColor','g')
            % plot(locs_Rwave,smoothECG(locs_Rwave),'rv','MarkerFaceColor','r')
            % plot(locs_Swave,smoothECG(locs_Swave),'rs','MarkerFaceColor','b')
            % ylabel("Altura")
            % xlabel("Muestras")
            % title("ECG Original")
            %  legend('Señal suavizada (ECG)','Punto Q','Punto R','Punto S')
            % drawnow
            % pause

            hold(app.PW,"on");
            plot(app.PW,app.s_k);
            %figure
            %plot(highpass(s_k,0.1))
            plot(app.PW,smoothECG);
            plot(app.PW,locs_Qwave,smoothECG(locs_Qwave),'rs','MarkerFaceColor','g');
            plot(app.PW,locs_Rwave,smoothECG(locs_Rwave),'rv','MarkerFaceColor','r');
            plot(app.PW,locs_Swave,smoothECG(locs_Swave),'rs','MarkerFaceColor','b');
            grid(app.PW,"on");
            title(app.PW,'Complejo QRS');
            xlabel(app.PW,'Muestras');
            ylabel(app.PW,'Voltage Normalizado');
            %ax = axis;
            %axis([0 1850 -1.1 1.1])
            legend(app.PW,'Señal preprocesada','Señal suavizada (ECG)','Punto Q','Punto R','Punto S');

            %legend(app.PW,'Señal suavizada (ECG)','Onda Q','Onda R','Onda S')

            %             plot(app.PW, app.s_k);
            %             axis off
            s_k=s_k1


            atmp=s_k;%(s_k+max(s_k))/(.8*2*max(s_k));
            atmp(atmp<0)=0;
            [a,l]=findpeaks(atmp.^6,'MinPeakDistance',25,'MinPeakHeight',.8*max(atmp));
            mind=mean(diff(l));
            rango_d=max(s_k)-min(s_k);
            s_k=s_k./(.5*(rango_d));
            rango_d=max(s_k)-min(s_k);
            mind=.8*mind;
            smoothECG = sgolayfilt(s_k,7,21);

            [a,l,w,p]=findpeaks(smoothECG,'MinPeakProminence',.1*max(s_k),'MinPeakDistance',mind/9,'Annotate','extents','WidthReference','halfprom');
            % historia(n).pks = l;

            [rh,locs_Rwave] = findpeaks((s_k),'MinPeakHeight',0.2,...
                'MinPeakDistance',mind);
            ECG_inverted = -s_k;
            [sh,locs_Swave]=findpeaks(ECG_inverted,'MinPeakHeight',0.2,...
                'MinPeakDistance',mind);

            [mh,min_locs] = findpeaks(-smoothECG,'MinPeakProminence',.05*rango_d);

            %Peaks between
            locs_Qwave = min_locs(smoothECG(min_locs)>.9*max(-sh) & smoothECG(min_locs)<.9*max(smoothECG));%.3*(max(s_k)-min(s_k)-1));
            % figure


            nbin=26;

            bins=linspace(0,1,nbin);
            puntos=l;

            dp=diff(puntos,1,2);
            dp=dp./max(dp')';
            bins=linspace(0,max(max(dp)),nbin);
            hc=histc(dp',bins)';
            hc=hc(1:end,2:end);
            bins=bins(2:end);
            hc=hc./max(hc')';
            %Ahora puntos va a llevar la data del conteo del histograma
            puntos=hc;


            % dp_prod=diff([a],1,2);
            % dp_prod=dp_prod./max(dp_prod')';
            % bins=linspace(0,max(max(dp_prod)),nbin);
            % hc_prod=histc(dp_prod',bins)';
            % hc_prod=hc_prod(1:end,2:end);
            % bins=bins(2:end);
            % hc_prod=hc_prod./max(hc_prod')';
            SVMModel = load("svm.mat").SVMModel;
            [Y_pred_svm,score] = predict(SVMModel,hc);
            
            if Y_pred_svm=='1'
                resul="NORMAL";
                disp(score);
            else
                resul="Con Bloqueo AV";
                disp(score);
            end

            app.RESULTADOLabel.Text=strcat("RESULTADO: ",strcat(resul,num2str(score)));
            app.ExpedienteLabel.Text=strcat("Número de Expediente: ",app.Expediente);
            app.EdadLabel.Text=strcat("Edad: ",app.Edad);
            app.SexoLabel.Text=strcat("Sexo: ",app.Sexo);
            drawnow
        end

        % Button pushed function: SiButton
        function SiButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                % savefig(app.PW,"as.pdf")
                % savefig(app.QRS,"as.pdf")
                exportapp(app.ECGUIFigure,[filepath filename]);
                msg = 'Analisis guardado ¿Desea hacer otro análisis?';
                selection = uiconfirm(app.ECGUIFigure,msg,'Continuar', ...
                    'Options',{'SI','NO'});
                switch selection
                    case 'SI'
                        close all;
                        app4();
                        delete(app)
                    case 'NO'
                        delete(app)
                        return
                end
                % saveas(app.ECGUIFigure,"auto","pdf")
            end
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            close all;
            app4();
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECGUIFigure = uifigure('Visible', 'off');
            app.ECGUIFigure.Name = 'ECG';
            app.QRS = uiaxes(app.ECGUIFigure);
            title(app.QRS, 'Title')
            xlabel(app.QRS, 'X')
            ylabel(app.QRS, 'Y')
            zlabel(app.QRS, 'Z')
            app.QRS.Visible = 'off';
            app.PW = uiaxes(app.ECGUIFigure);
            title(app.PW, 'Title')
            xlabel(app.PW, 'X')
            ylabel(app.PW, 'Y')
            zlabel(app.PW, 'Z')
            app.PW.Visible = 'off';
            app.TiraderitmoLabel = uilabel(app.ECGUIFigure);
            app.TiraderitmoLabel.Text = 'Tira de ritmo';
            app.Image = uiimage(app.ECGUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-upiita.png');
            app.TextArea = uitextarea(app.ECGUIFigure);
            app.ComentariosLabel = uilabel(app.ECGUIFigure);
            app.ComentariosLabel.Text = 'Comentarios';
            app.Image2 = uiimage(app.ECGUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'corazón.jpg');
            app.SiButton = uibutton(app.ECGUIFigure, 'push');
            app.SiButton.ButtonPushedFcn = createCallbackFcn(app, @SiButtonPushed, true);
            app.SiButton.Text = 'Si';
            app.NoButton = uibutton(app.ECGUIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.DeseaguardarelanaliticoLabel = uilabel(app.ECGUIFigure);
            app.DeseaguardarelanaliticoLabel.Text = '¿Desea guardar el analitico?';
            app.RESULTADOLabel = uilabel(app.ECGUIFigure);
            app.RESULTADOLabel.Text = 'RESULTADO:';
            app.ExpedienteLabel = uilabel(app.ECGUIFigure);
            app.ExpedienteLabel.Text = 'Expediente';
            app.EdadLabel = uilabel(app.ECGUIFigure);
            app.EdadLabel.Text = 'Edad';
            app.SexoLabel = uilabel(app.ECGUIFigure);
            app.SexoLabel.Text = 'Sexo';
            % Show the figure after all components are created
            app.ECGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGUIFigure)
        end
    end
end